classdef appOPMrubin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_2                        matlab.ui.container.Panel
        Switch                         matlab.ui.control.Switch
        OPMcomPortsEditField           matlab.ui.control.EditField
        COMportsEditFieldLabel         matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        okButton                       matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        ChoosewavelengthDropDown       matlab.ui.control.DropDown
        ChoosewavelengthDropDownLabel  matlab.ui.control.Label
        SavepowerintableButton         matlab.ui.control.Button
        DisplaywavelengthButton        matlab.ui.control.Button
        ReadpowerButton                matlab.ui.control.Button
        OpticalpowermetersRUBINLabel   matlab.ui.control.Label
    end

    properties (Access = private)
        CallingApp
        folder
        userText
    end
    properties (Access = public)
        opmArr
    end
    methods (Access = public)
        function output = connect(app)
            try
                app.opmArr = [];
                app.opmArr = OpticalPowerMeterArr(app);
                output = getInfoOpenComArr(app.opmArr);
                app.Panel.Enable = 'on';
            catch ME
                fig = uifigure;
                output = "Connection failed";
                uialert(fig,ME.message,output);
                app.Switch.Value = "Disconnect";
            end
        end
        function output = disconnect(app)
            if ~isequal(class(app.opmArr),'double')
                app.opmArr = deleteVirtualObjectArr(app.opmArr);
                output = getInfoCloseComArr(app.opmArr);
                app.Panel.Enable = 'off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp = MainApp;
            
            app.folder = MainApp.folder;
            app.userText = MainApp.userText;
            
            %название файла для сохранения данных ?
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Connect"
                output = connect(app);
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            addStrOutput(app.CallingApp,output);
        end

        % Callback function
        function RunButtonPushed(app, event)
            value = app.Tree.SelectedNodes;
            if value == app.ReadpowerRubinNode
                
            elseif value == app.DispwavelengthRubinNode
                
            elseif value == app.SavepowertableNode
                
            end
            
        end

        % Button pushed function: ReadpowerButton
        function ReadpowerButtonPushed(app, event)
            app.opmArr = readPowerdBmArr(app.opmArr);
                output = getInfoPowerArr(app.opmArr);
                addStrOutput(app.CallingApp,output);
        end

        % Button pushed function: DisplaywavelengthButton
        function DisplaywavelengthButtonPushed(app, event)
            output = getInfoWavelengthArr(app.opmArr);
            addStrOutput(app.CallingApp,output);
        end

        % Button pushed function: SavepowerintableButton
        function SavepowerintableButtonPushed(app, event)
            output = "function is not ready";
            addStrOutput(app.CallingApp,output);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpticalpowermetersRUBINLabel = uilabel(app.UIFigure);
            app.OpticalpowermetersRUBINLabel.Text = 'Optical power meters (RUBIN)';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Enable = 'off';
            app.ReadpowerButton = uibutton(app.Panel, 'push');
            app.ReadpowerButton.ButtonPushedFcn = createCallbackFcn(app, @ReadpowerButtonPushed, true);
            app.ReadpowerButton.Text = 'Read power';
            app.DisplaywavelengthButton = uibutton(app.Panel, 'push');
            app.DisplaywavelengthButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaywavelengthButtonPushed, true);
            app.DisplaywavelengthButton.Text = 'Display wavelength';
            app.SavepowerintableButton = uibutton(app.Panel, 'push');
            app.SavepowerintableButton.ButtonPushedFcn = createCallbackFcn(app, @SavepowerintableButtonPushed, true);
            app.SavepowerintableButton.Text = 'Save power in table';
            app.ChoosewavelengthDropDownLabel = uilabel(app.Panel);
            app.ChoosewavelengthDropDownLabel.Text = 'Choose wavelength';
            app.ChoosewavelengthDropDown = uidropdown(app.Panel);
            app.ChoosewavelengthDropDown.Items = {'850 nm', '980 nm', '1310 nm', '1490 nm', '1550 nm', '1650 nm'};
            app.ChoosewavelengthDropDown.Enable = 'off';
            app.ChoosewavelengthDropDown.Value = '1550 nm';
            app.EditField = uieditfield(app.Panel, 'text');
            app.okButton = uibutton(app.Panel, 'push');
            app.okButton.Enable = 'off';
            app.okButton.Text = 'ok';
            app.Panel_2 = uipanel(app.UIFigure);
            app.COMportsEditFieldLabel = uilabel(app.Panel_2);
            app.COMportsEditFieldLabel.Text = 'COM-ports';
            app.OPMcomPortsEditField = uieditfield(app.Panel_2, 'text');
            app.OPMcomPortsEditField.Value = 'COM19 COM12';
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.Items = {'Disconnect', 'Connect'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Disconnect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appOPMrubin(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end